classdef getDataGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        ReadButton                matlab.ui.control.Button
        StopButton                matlab.ui.control.Button
        ConnectButton             matlab.ui.control.Button
        Label                     matlab.ui.control.Label
        COMEditFieldLabel         matlab.ui.control.Label
        COMEditField              matlab.ui.control.EditField
        StatusLampLabel           matlab.ui.control.Label
        StatusLamp                matlab.ui.control.Lamp
        NumSensorsEditFieldLabel  matlab.ui.control.Label
        NumSensorsEditField       matlab.ui.control.NumericEditField
        TestEditFieldLabel        matlab.ui.control.Label
        TestEditField             matlab.ui.control.EditField
        DisconnectButton          matlab.ui.control.Button
        GestureKnobLabel          matlab.ui.control.Label
        GestureKnob               matlab.ui.control.DiscreteKnob
    end


    properties (Access = private)
        stopButtonPressed % Description
        arduinoA % Description
    end

    methods (Access = private)
    
        function enableButtons(app)
            app.ReadButton.Enable = "on";
            app.StopButton.Enable = "on";
            % app.TestEditField.Enable = "off";
            
            app.ConnectButton.Enable = "off";
            app.COMEditField.Enable = "off";
            app.NumSensorsEditField.Enable = "off";
        end
        
        function disableButtons(app)
            app.ReadButton.Enable = "off";
            app.StopButton.Enable = "off";
            % app.TestEditField.Enable = "on";
            
            app.ConnectButton.Enable = "on";
            app.COMEditField.Enable = "on";
            app.NumSensorsEditField.Enable = "on";
        end
        
    end


    methods (Access = private)

        % Button pushed function: ConnectButton
        function ConnectButtonPushed(app, event)
            if ~isempty(instrfind)
                fclose(instrfind);
                delete(instrfind);
            end
            nSens = app.NumSensorsEditField.Value;
            COM = app.COMEditField.Value;
            if nSens > 0
                app.arduinoA=serial(COM,'BaudRate',9600);
                fopen(app.arduinoA);
                enableButtons(app);
                app.StatusLamp.Color = 'g';
            else
                app.Label.Text = 'Num. Sensors = 0';
            end
        end

        % Button pushed function: ReadButton
        function ReadButtonPushed(app, event)
            app.stopButtonPressed = true;
            
            formatARD = '';
            nCount = 1;     % initial sample
            data = [];      % variable to save data
            Sample = [];    % sample stamp
            clockT = [];
            nSens = app.NumSensorsEditField.Value;
            for nSen = 0: nSens
                if nSen < nSens
                    formatARD = [formatARD , '%f,'];
                else
                    formatARD = [formatARD , '%f'];
                end
            end
            app.StatusLamp.Color = 'b';
            
            % reading
            while app.stopButtonPressed
                 data(:,nCount)= fscanf(app.arduinoA,formatARD);
                 Sample(:,nCount) = nCount;
                 clockT(:,nCount) = clock;
                 nCount = nCount + 1;
                 app.Label.Text = int2str(nCount);
                 drawnow()
            end
            app.StatusLamp.Color = 'g';
            app.Label.Text = '0';
            
            % formatting
            format1 = ''; format2 = '';
            format1 = '%12s%15s'; format2 = '%12.0f%15s';
            str = strings([1,(nSen + 2)]);
            str(1) = 'Sample'; str(2) = 'Time';
            for nSen = 0: nSens
                if nSen < nSens
                    format1 = [format1 , '%12s'];
                    format2 = [format2 , '%12.2f'];
                    str(nSen+3) = ['A', int2str(nSen)];
                else
                    format1 = [format1 , '%12s\r\n'];
                    format2 = [format2 , '%12.2f\r\n'];
                    str(nSen+3) = ['A', int2str(nSen)];
                end
            end
            
            % preapring data
            clockT = clockT(4:6,:);
            clockStr = string(clockT);
            time = join(clockStr');
            time = strrep(time,' ','');
            gesture = app.GestureKnob.Value;
            
            % saving data
            folderName = 'testsData';
            [status, msg, msgID] = mkdir(folderName);
            dir = [pwd, '\',folderName];
            editField = app.TestEditField.Value;
            S = char(datetime('now','Format','yyyy-MM-dd''T''HHmmss'));
            fileName = [editField,gesture,S,'.txt'];
            C = cat(1, Sample, time', data);
            fileID = fopen(fullfile(dir,fileName),'wt');
            fprintf(fileID, format1, str);      % col titles
            fprintf(fileID, format2, C);        % col data
            fclose(fileID);
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            app.stopButtonPressed = false;
            app.StatusLamp.Color = 'r';
            disableButtons(app);
            fclose(app.arduinoA);
        end

        % Button pushed function: DisconnectButton
        function DisconnectButtonPushed(app, event)
            app.StatusLamp.Color = 'r';
            disableButtons(app);
            fclose(app.arduinoA);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.ReadButton = uibutton(app.UIFigure, 'push');
            app.ReadButton.ButtonPushedFcn = createCallbackFcn(app, @ReadButtonPushed, true);
            app.ReadButton.Enable = 'off';
            app.ReadButton.Text = 'Read';
            app.StopButton = uibutton(app.UIFigure, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Enable = 'off';
            app.StopButton.Text = 'Stop';
            app.ConnectButton = uibutton(app.UIFigure, 'push');
            app.ConnectButton.ButtonPushedFcn = createCallbackFcn(app, @ConnectButtonPushed, true);
            app.ConnectButton.Text = 'Connect';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '0';
            app.COMEditFieldLabel = uilabel(app.UIFigure);
            app.COMEditFieldLabel.Text = 'COM';
            app.COMEditField = uieditfield(app.UIFigure, 'text');
            app.COMEditField.Value = 'COM7';
            app.StatusLampLabel = uilabel(app.UIFigure);
            app.StatusLampLabel.Text = 'Status';
            app.StatusLamp = uilamp(app.UIFigure);
            app.NumSensorsEditFieldLabel = uilabel(app.UIFigure);
            app.NumSensorsEditFieldLabel.Text = 'Num. Sensors';
            app.NumSensorsEditField = uieditfield(app.UIFigure, 'numeric');
            app.NumSensorsEditField.Value = 9;
            app.TestEditFieldLabel = uilabel(app.UIFigure);
            app.TestEditFieldLabel.Enable = 'off';
            app.TestEditFieldLabel.Text = 'Test:';
            app.TestEditField = uieditfield(app.UIFigure, 'text');
            app.TestEditField.Value = 'test_name';
            app.DisconnectButton = uibutton(app.UIFigure, 'push');
            app.DisconnectButton.ButtonPushedFcn = createCallbackFcn(app, @DisconnectButtonPushed, true);
            app.DisconnectButton.Text = 'Disconnect';
            app.GestureKnobLabel = uilabel(app.UIFigure);
            app.GestureKnobLabel.Text = 'Gesture';
            app.GestureKnob = uiknob(app.UIFigure, 'discrete');
            app.GestureKnob.Items = {'G1', 'G2', 'G3', 'G4', 'G5', 'G6'};
            app.GestureKnob.Value = 'G1';
        end
    end

    methods (Access = public)

        % Construct app
        function app = getDataGUI

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end