classdef getIMUdata < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        StartButton          matlab.ui.control.StateButton
        ReadyLampLabel       matlab.ui.control.Label
        ReadyLamp            matlab.ui.control.Lamp
        MovementLabel        matlab.ui.control.Label
        MovementDropDown     matlab.ui.control.DropDown
        RunningModeCheckBox  matlab.ui.control.CheckBox
        Label                matlab.ui.control.Label
    end


    methods (Access = private)
    
        function startFunc(app)
            kindOfMov = app.MovementDropDown.Value;
            
            %% COM port cleaning
            if ~isempty(instrfind)
                fclose(instrfind);
                delete(instrfind);
            end
            
            %% getting ready
            app.ReadyLampLabel.Text = 'Connecting IMU sensors';
            baudrate = 921600;              % rate at which information is transferred
            nCount = 1;
            flagIMUData = false;            % IMU sync flag
            
            % create nIMUs instances
            if app.RunningModeCheckBox.Value
                lpSensor1 = lpms();
                lpSensor2 = lpms();
                % connecting
                if (~lpSensor1.connect('COM9', baudrate) || ~lpSensor2.connect('COM10', baudrate))
                    app.ReadyLampLabel.Text ='Sensors not connected, closing';
                    disp('Review COM ports on your PC, use seriallist command to know the COM ports connected')
                    pause(5);
                    close(app.UIFigure);
                    return 
                end
                % Set streaming mode
                lpSensor1.setStreamingMode();
                lpSensor2.setStreamingMode();
                
                app.ReadyLampLabel.Text ='All set up';
            end

            app.ReadyLampLabel.Text = 'reading';
            app.ReadyLamp.Color = 'b';
            while app.StartButton.Value
                if app.RunningModeCheckBox.Value
                    dataIMU1 = lpSensor1.getQueueSensorData(); % getCurrentSensorData();
                    dataIMU2 = lpSensor2.getQueueSensorData(); % getCurrentSensorData();
                    % Sync Method
                    if (~isempty(dataIMU1)) && (~isempty(dataIMU2))
                        flagIMUData = true;
                    elseif (~isempty(dataIMU1)) && (isempty(dataIMU2))
                        while (isempty(dataIMU2))
                            dataIMU2 = lpSensor2.getQueueSensorData();
                        end
                        flagIMUData = true;
                    elseif (~isempty(dataIMU2)) && (isempty(dataIMU1))
                        while (isempty(dataIMU1))
                            dataIMU1 = lpSensor1.getQueueSensorData();
                        end
                        flagIMUData = true;
                    elseif (isempty(dataIMU2)) && (isempty(dataIMU1))
                        flagIMUData = false;
                    end
                    % Synchronized data
                    if flagIMUData
                        Sample(nCount,:)	= nCount;
                        clockT(nCount,:)	= clock;
                        StampA(nCount,:)    = dataIMU1.timestamp;
                        QuatA(nCount,:)     = dataIMU1.quat;
                        AccA(nCount,:)      = dataIMU1.acc;
                        GyrA(nCount,:)      = dataIMU1.gyr;
                        StampB(nCount,:)    = dataIMU2.timestamp;
                        QuatB(nCount,:)     = dataIMU2.quat;
                        AccB(nCount,:)      = dataIMU2.acc;
                        GyrB(nCount,:)      = dataIMU2.gyr;
                        nCount = nCount +1;
                    end
                end
                drawnow
            end
            
            app.ReadyLampLabel.Text ='Disconnecting';
            
            if app.RunningModeCheckBox.Value
                if (lpSensor1.disconnect() && lpSensor1.disconnect())
                    app.ReadyLampLabel.Text ='Sensor disconnected';
                end
                %% storing
                time = [clockT(:,4) clockT(:,5) clockT(:,6)];
                Data = table(Sample, time, StampA, QuatA, AccA, GyrA, StampB, QuatB, AccB, GyrB);
                
                dateS = char(datetime('now','Format','yyyy-MM-dd''T''HHmmssSSS'));
                name = [kindOfMov,dateS,'.mat'];
                save(name, 'Data');
                app.ReadyLampLabel.Text = 'Data Saved';
            end
            
            app.StartButton.Text = 'Start';
            app.StartButton.FontColor  = 'k';
            app.ReadyLamp.Color = 'g';
            app.ReadyLampLabel.Text = 'Ready';
        end
    
        function timer(app)
            count = 0;
            while app.StartButton.Value
                app.Label.Text = str(count);
                count = count + 1;
                drawnow
                pause(1);
            end
        end
        
    end


    methods (Access = private)

        % Value changed function: StartButton
        function StartButtonValueChanged(app, event)
            value = app.StartButton.Value;
            if value == 1
                app.StartButton.Text = 'Stop';
                app.StartButton.FontColor = 'r';
                app.ReadyLamp.Color = 'y';
                app.ReadyLampLabel.Text = 'preparing';
                startFunc(app);
                % timer(app);
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.StartButton = uibutton(app.UIFigure, 'state');
            app.StartButton.ValueChangedFcn = createCallbackFcn(app, @StartButtonValueChanged, true);
            app.StartButton.Text = 'Start';
            app.ReadyLampLabel = uilabel(app.UIFigure);
            app.ReadyLampLabel.Text = 'Ready';
            app.ReadyLamp = uilamp(app.UIFigure);
            app.MovementLabel = uilabel(app.UIFigure);
            app.MovementLabel.Text = 'Movement:';
            app.MovementDropDown = uidropdown(app.UIFigure);
            app.MovementDropDown.Items = {'Frontal', 'Lateral', 'Cruzado Int', 'Cruzado Ext', 'Rotación Z', 'Combo', 'Otro'};
            app.MovementDropDown.Editable = 'on';
            app.MovementDropDown.Value = 'Frontal';
            app.RunningModeCheckBox = uicheckbox(app.UIFigure);
            app.RunningModeCheckBox.Text = 'Running Mode';
            app.RunningModeCheckBox.Value = true;
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '0';
        end
    end

    methods (Access = public)

        % Construct app
        function app = getIMUdata

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end