classdef get_IMU_Flex_DataGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        StartButton             matlab.ui.control.Button
        StopButton              matlab.ui.control.Button
        COMIMUEditFieldLabel    matlab.ui.control.Label
        COMIMUEditField         matlab.ui.control.EditField
        COMARDEditFieldLabel    matlab.ui.control.Label
        COMARDEditField         matlab.ui.control.EditField
        ConnectIMUButton        matlab.ui.control.Button
        DisconnectIMUButton     matlab.ui.control.Button
        Label                   matlab.ui.control.Label
        StatusLampLabel         matlab.ui.control.Label
        StatusLamp              matlab.ui.control.Lamp
        GestureKnobLabel        matlab.ui.control.Label
        GestureKnob             matlab.ui.control.DiscreteKnob
        InitialsEditFieldLabel  matlab.ui.control.Label
        InitialsEditField       matlab.ui.control.EditField
    end


    properties (Access = private)
        lpSensor % Description
        arduino % Description
        stopButtonPressed % Description
    end

    methods (Access = private)
    
        function enableButtons(app)
            app.StartButton.Enable = "on";
            app.StopButton.Enable = "on";
        end
        
        function disableButtons(app)
            app.StartButton.Enable = "off";
            app.StopButton.Enable = "off";
        end
        
    end


    methods (Access = private)

        % Button pushed function: ConnectIMUButton
        function ConnectIMUButtonPushed(app, event)
            COMPort = app.COMIMUEditField.Value;
            baudrate = 921600;          % rate at which information is transferred
            app.lpSensor = lpms();          % function lpms API sensor given by LPMS
            if ( ~app.lpSensor.connect(COMPort, baudrate) )
                disp('IMU not connected')
                return 
            end
            app.lpSensor.setStreamingMode();
            disp('IMU ready')
            enableButtons(app);
            app.StatusLamp.Color = 'g';
        end

        % Button pushed function: DisconnectIMUButton
        function DisconnectIMUButtonPushed(app, event)
            if (app.lpSensor.disconnect())
                disp('Sensor disconnected')
            end
            disableButtons(app)
            app.StatusLamp.Color = 'r';
            if ~isempty(instrfind)
                fclose(instrfind);
                delete(instrfind);
            end
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)

            app.stopButtonPressed = true;
            app.StartButton.Enable = "off";
            % arduino Connection
            app.arduino=serial('COM7','BaudRate',9600);
            fopen(app.arduino);
            flushinput(app.arduino)
            % parameters
            %nTotal = 500;   % samples number
            nCount = 1;     % initial sample
            data = [];      % variable to save data
            nSens = 9;      % number of sensors including zero
            Sample = [];    % sample stamp
            clockT = [];    % realTime
            dataIMU = [];   % IMU data
            % format incoming data
            format = '';
            for nSen = 0: nSens
                if nSen < nSens
                    format = [format , '%f,'];
                else
                    format = [format , '%f'];
                end
            end
            % reading
            disp('reading')
            while app.stopButtonPressed
                d_IMU = app.lpSensor.getQueueSensorData();
                if (~isempty(d_IMU))
                    dataIMU(:,nCount) = [d_IMU.timestamp, d_IMU.quat, d_IMU.acc];
                    data(:,nCount)= fscanf(app.arduino,format);
                    Sample(:,nCount) = nCount;
                    clockT(:,nCount) = clock;
                    app.Label.Text = int2str(nCount);
                    nCount = nCount + 1;
                end
                if nCount > 50
                    app.StatusLamp.Color = 'b';
                end
                drawnow()
            end
            app.StatusLamp.Color = 'g';
            %% Storing data seccion
            % formatting
            %format1 = ''; format2 = '';
            format1 = '%12s%12s%12s%12s%12s%12s%12s%12s%12s%12s%12s%12s'; 
            format2 = '%12.0f%12d%12d%12.3f%12.2f%12.6f%12.6f%12.6f%12.6f%12.5f%12.5f%12.5f';
            
            str = strings([1,(nSen + 12)]);
            str(1) = 'Sample'; str(2) = 'Hour'; str(3) = 'Minute'; str(4) = 'Sec';
            str(5) = 'Stamp'; str(6) = 'Quat1'; str(7) = 'Quat2'; str(8) = 'Quat3'; str(9) = 'Quat4';
            str(10) = 'AccX'; str(11) = 'AccY'; str(12) = 'AccZ';
            for nSen = 0: nSens
                if nSen < nSens
                    format1 = [format1 , '%12s'];
                    format2 = [format2 , '%12.2f'];
                    str(nSen+13) = ['A', int2str(nSen)];
                else
                    format1 = [format1 , '%12s\r\n'];
                    format2 = [format2 , '%12.2f\r\n'];
                    str(nSen+13) = ['A', int2str(nSen)];
                end
            end
            
            % storing Data
            
            clockT2 = clockT(4:6,:);
            Hour = clockT2(1,:);
            Min = clockT2(2,:);
            Sec = clockT2(3,:);
            
            folderName = 'testsData';
            [status, msg, msgID] = mkdir(folderName);
            dir = [pwd, '\',folderName];
            editFieldText = app.InitialsEditField.Value;
            gesture = app.GestureKnob.Value;
            S = char(datetime('now','Format','yyyy-MM-dd''T''HHmmssSSS'));
            fileName = [editFieldText, '_', gesture, '_', S,'.txt'];
            C = cat(1, Sample, Hour, Min, Sec, dataIMU, data);
            fileID = fopen(fullfile(dir,fileName),'wt');
            fprintf(fileID, format1, str); % col names
            fprintf(fileID, format2, C);
            fclose(fileID);
            disp('Done')
            app.StartButton.Enable = "on";
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            app.stopButtonPressed = false;
            fclose(app.arduino);
            app.Label.Text = "0";
            app.StartButton.Enable = "on";
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Enable = 'off';
            app.StartButton.Text = 'Start';
            app.StopButton = uibutton(app.UIFigure, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Enable = 'off';
            app.StopButton.Text = 'Stop';
            app.COMIMUEditFieldLabel = uilabel(app.UIFigure);
            app.COMIMUEditFieldLabel.Text = 'COM IMU';
            app.COMIMUEditField = uieditfield(app.UIFigure, 'text');
            app.COMIMUEditField.Value = 'COM4';
            app.COMARDEditFieldLabel = uilabel(app.UIFigure);
            app.COMARDEditFieldLabel.Text = 'COM ARD';
            app.COMARDEditField = uieditfield(app.UIFigure, 'text');
            app.COMARDEditField.Value = 'COM7';
            app.ConnectIMUButton = uibutton(app.UIFigure, 'push');
            app.ConnectIMUButton.ButtonPushedFcn = createCallbackFcn(app, @ConnectIMUButtonPushed, true);
            app.ConnectIMUButton.Text = 'Connect IMU';
            app.DisconnectIMUButton = uibutton(app.UIFigure, 'push');
            app.DisconnectIMUButton.ButtonPushedFcn = createCallbackFcn(app, @DisconnectIMUButtonPushed, true);
            app.DisconnectIMUButton.Text = 'Disconnect IMU';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '0';
            app.StatusLampLabel = uilabel(app.UIFigure);
            app.StatusLampLabel.Text = 'Status';
            app.StatusLamp = uilamp(app.UIFigure);
            app.GestureKnobLabel = uilabel(app.UIFigure);
            app.GestureKnobLabel.Text = 'Gesture';
            app.GestureKnob = uiknob(app.UIFigure, 'discrete');
            app.GestureKnob.Items = {'Green_Side', 'Green_Fron', 'Blue_F', 'Purple_F', 'Black_F', 'Purp_INV_F', 'Black_INV_F'};
            app.GestureKnob.Value = 'Green_Side';
            app.InitialsEditFieldLabel = uilabel(app.UIFigure);
            app.InitialsEditFieldLabel.Text = 'Initials';
            app.InitialsEditField = uieditfield(app.UIFigure, 'text');
            app.InitialsEditField.Value = 'AFCG';
        end
    end

    methods (Access = public)

        % Construct app
        function app = get_IMU_Flex_DataGUI

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end